classdef ElementContributions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        ElementNumberDropDownLabel    matlab.ui.control.Label
        ElementNumberDropDown         matlab.ui.control.DropDown
        UIAxes_2                      matlab.ui.control.UIAxes
        FocusXSliderLabel             matlab.ui.control.Label
        FocusXSlider                  matlab.ui.control.Slider
        FocusZSliderLabel             matlab.ui.control.Label
        FocusZSlider                  matlab.ui.control.Slider
        ResetFocusButton              matlab.ui.control.Button
        NumberofElementsSliderLabel   matlab.ui.control.Label
        NumberofElementsSlider        matlab.ui.control.Slider
        ElementDimensionRSliderLabel  matlab.ui.control.Label
        ElementDimensionRSlider       matlab.ui.control.Slider
        MajorAxisSliderLabel          matlab.ui.control.Label
        MajorAxisSlider               matlab.ui.control.Slider
        MinorAxisSliderLabel          matlab.ui.control.Label
        MinorAxisSlider               matlab.ui.control.Slider
        UIFieldAxes                   matlab.ui.control.UIAxes
        NumberofElementsYSliderLabel  matlab.ui.control.Label
        NumberofElementsYSlider       matlab.ui.control.Slider
        PlaneButtonGroup              matlab.ui.container.ButtonGroup
        xyButton                      matlab.ui.control.RadioButton
        xzButton                      matlab.ui.control.RadioButton
        yzButton                      matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        n_elements_r
        n_elements_y
        a
        b
        D
        R_focus
        type
        focus
        kerf
        rect2D
        rect3D
        fo
        focus_point;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.n_elements_r = 42;
            app.n_elements_y = 6;
            app.a = 118/1000;
            app.b = 90/1000;
            app.D = [5, 6]/1000;
            app.R_focus = app.a;
            app.type = 'horizontal';
            app.focus = [0,0,0]/1000;
            app.kerf = 0.4/1000;
            
            app.rect2D = kwave_focused_array(app.n_elements_r, 1,...,
            app.kerf, app.D, app.R_focus, app.a, app.b, app.type);
            
            app.rect3D = kwave_focused_array(app.n_elements_r,app.n_elements_y,...,
            app.kerf, app.D, app.R_focus, app.a, app.b, app.type);
            
            app.fo = 650e3;
            app.focus_point= app.focus([1,3]);
            
            slider_limits = [-45,45];
            slider_ticks = -40:10:40;
            app.NumberofElementsSlider.Limits = [42,64];
            app.NumberofElementsSliderLabel.Text = sprintf('N Elements R: %0.2f',app.n_elements_r);
            app.ElementDimensionRSlider.Limits = [4,8];
            app.ElementDimensionRSliderLabel.Text = sprintf('Element Dimension R: %0.2f (mm)',app.D(1)*1000);
            app.MajorAxisSlider.Limits = [90,120];
            app.MajorAxisSliderLabel.Text = sprintf('Major Axis: %0.2f (mm)',app.a*1000);
            app.MinorAxisSlider.Limits = [75,120];
            app.MinorAxisSliderLabel.Text = sprintf('Minor Axis: %0.2f (mm)',app.b*1000);
            app.FocusXSlider.Limits = slider_limits;
            app.FocusXSlider.MajorTicks = slider_ticks;
            app.FocusZSlider.Limits = slider_limits;
            app.FocusZSlider.MajorTicks = slider_ticks;
            app.FocusZSliderLabel.Text = sprintf('Focus Z: %0.2f (mm)',app.FocusZSlider.Value);
            app.FocusXSliderLabel.Text = sprintf('Focus X: %0.2f (mm)',app.FocusXSlider.Value);
            Items = {};
            for i = 1:app.n_elements_r
                Items{i} = num2str(i);
            end
            app.ElementNumberDropDown.Items = Items;
            
            % Configure Axis
            title(app.UIAxes_2, 'Contributions')
            title(app.UIAxes, 'Beam Profile')
            configure_axes(app);
            
            plot_transducers_field(app);
        end

        % Callback function
        function ViewSingleButtonValueChanged(app, event)
            value = app.ViewSingleButton.Value;
            plot_transducers_field(app);
        end

        % Value changed function: ElementNumberDropDown
        function ElementNumberDropDownValueChanged(app, event)
            value = app.ElementNumberDropDown.Value;
            plot_transducers_field(app);
        end

        % Value changed function: FocusXSlider
        function FocusXSliderValueChanged(app, event)
            app.focus(1) = app.FocusXSlider.Value/1000;
            app.FocusXSliderLabel.Text = sprintf('Focus X: %0.2f (mm)',app.FocusXSlider.Value);
            plot_transducers_field(app);
        end

        % Value changed function: FocusZSlider
        function FocusZSliderValueChanged(app, event)
            app.focus(3) = app.FocusZSlider.Value/1000;
            app.FocusZSliderLabel.Text = sprintf('Focus Z: %0.2f (mm)',app.FocusZSlider.Value);
            plot_transducers_field(app);
        end

        % Button pushed function: ResetFocusButton
        function ResetFocusButtonPushed(app, event)
            app.FocusZSlider.Value = 0;
            app.FocusXSlider.Value = 0;
            app.focus = [0,0,0];
            plot_transducers_field(app);
        end

        % Value changed function: NumberofElementsSlider
        function NumberofElementsSliderValueChanged(app, event)
            app = geometry_slider(app, 'NumberofElementsSlider','n_elements_r', 42:64);
            app.NumberofElementsSliderLabel.Text = sprintf('N Elements R: %0.2f',app.n_elements_r);
            plot_transducers_field(app);
            
        end

        % Value changed function: ElementDimensionRSlider
        function ElementDimensionRSliderValueChanged(app, event)
            app = geometry_slider(app, 'ElementDimensionRSlider','D', 4:8);
            app.ElementDimensionRSliderLabel.Text = sprintf('Element Dimension R: %0.2f (mm)',app.D(1)*1000);
            plot_transducers_field(app);
        end

        % Value changed function: MajorAxisSlider
        function MajorAxisSliderValueChanged(app, event)
            app = geometry_slider(app, 'MajorAxisSlider','a', 90:120);
            app.MajorAxisSliderLabel.Text = sprintf('Major Axis: %0.2f (mm)',app.a*1000);
            configure_axes(app);
            plot_transducers_field(app);
        end

        % Value changed function: MinorAxisSlider
        function MinorAxisSliderValueChanged(app, event)
            app = geometry_slider(app, 'MinorAxisSlider','b', 75:120);
            app.MinorAxisSliderLabel.Text = sprintf('Minor Axis: %0.2f (mm)',app.b*1000);
            plot_transducers_field(app);    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ElementNumberDropDownLabel = uilabel(app.UIFigure);
            app.ElementNumberDropDownLabel.Text = 'Element Number';
            app.ElementNumberDropDown = uidropdown(app.UIFigure);
            app.ElementNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementNumberDropDownValueChanged, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.FocusXSliderLabel = uilabel(app.UIFigure);
            app.FocusXSliderLabel.Text = 'Focus X';
            app.FocusXSlider = uislider(app.UIFigure);
            app.FocusXSlider.ValueChangedFcn = createCallbackFcn(app, @FocusXSliderValueChanged, true);
            app.FocusZSliderLabel = uilabel(app.UIFigure);
            app.FocusZSliderLabel.Text = 'Focus Z';
            app.FocusZSlider = uislider(app.UIFigure);
            app.FocusZSlider.ValueChangedFcn = createCallbackFcn(app, @FocusZSliderValueChanged, true);
            app.ResetFocusButton = uibutton(app.UIFigure, 'push');
            app.ResetFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFocusButtonPushed, true);
            app.ResetFocusButton.Text = 'Reset Focus';
            app.NumberofElementsSliderLabel = uilabel(app.UIFigure);
            app.NumberofElementsSliderLabel.Text = 'Number of Elements';
            app.NumberofElementsSlider = uislider(app.UIFigure);
            app.NumberofElementsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsSliderValueChanged, true);
            app.ElementDimensionRSliderLabel = uilabel(app.UIFigure);
            app.ElementDimensionRSliderLabel.Text = 'Element Dimension R';
            app.ElementDimensionRSlider = uislider(app.UIFigure);
            app.ElementDimensionRSlider.ValueChangedFcn = createCallbackFcn(app, @ElementDimensionRSliderValueChanged, true);
            app.MajorAxisSliderLabel = uilabel(app.UIFigure);
            app.MajorAxisSliderLabel.Text = 'Major Axis';
            app.MajorAxisSlider = uislider(app.UIFigure);
            app.MajorAxisSlider.ValueChangedFcn = createCallbackFcn(app, @MajorAxisSliderValueChanged, true);
            app.MinorAxisSliderLabel = uilabel(app.UIFigure);
            app.MinorAxisSliderLabel.Text = 'Minor Axis';
            app.MinorAxisSlider = uislider(app.UIFigure);
            app.MinorAxisSlider.ValueChangedFcn = createCallbackFcn(app, @MinorAxisSliderValueChanged, true);
            app.UIFieldAxes = uiaxes(app.UIFigure);
            title(app.UIFieldAxes, 'Field')
            xlabel(app.UIFieldAxes, 'X')
            ylabel(app.UIFieldAxes, 'Y')
            app.NumberofElementsYSliderLabel = uilabel(app.UIFigure);
            app.NumberofElementsYSliderLabel.Text = 'Number of Elements Y';
            app.NumberofElementsYSlider = uislider(app.UIFigure);
            app.PlaneButtonGroup = uibuttongroup(app.UIFigure);
            app.PlaneButtonGroup.Title = 'Plane';
            app.xyButton = uiradiobutton(app.PlaneButtonGroup);
            app.xyButton.Text = 'xy';
            app.xyButton.Value = true;
            app.xzButton = uiradiobutton(app.PlaneButtonGroup);
            app.xzButton.Text = 'xz';
            app.yzButton = uiradiobutton(app.PlaneButtonGroup);
            app.yzButton.Text = 'yz';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ElementContributions

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end