classdef GeometricModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        FocusXSliderLabel  matlab.ui.control.Label
        FocusXSlider       matlab.ui.control.Slider
        FocusZSliderLabel  matlab.ui.control.Label
        FocusZSlider       matlab.ui.control.Slider
    end

    
    properties (Access = public)
        n_elements_r
        n_elements_y
        a
        b
        D
        R_focus
        type
        focus
        kerf
        rect2D
        rect3D
        fo
        focus_point;
        lambda
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.n_elements_r = 42;
            app.n_elements_y = 6;
            app.a = 118/1000;
            app.b = 90/1000;
            app.D = [6, 6]/1000;
            app.R_focus = app.a;
            app.type = 'horizontal';
            app.focus = [0,0,0]/1000;
            app.kerf = 0.4/1000;
            
            app.rect2D = kwave_focused_array(app.n_elements_r, 1,...,
            app.kerf, app.D, app.R_focus, app.a, app.b, app.type);
            
            app.rect3D = kwave_focused_array(app.n_elements_r,app.n_elements_y,...,
            app.kerf, app.D, app.R_focus, app.a, app.b, app.type);
            
            app.fo = 650e3;
            app.focus_point= app.focus([1,3]);
            
            app.FocusZSliderLabel.Text = sprintf('Focus Z: %0.2f (mm)',app.FocusZSlider.Value);
            app.FocusXSliderLabel.Text = sprintf('Focus X: %0.2f (mm)',app.FocusXSlider.Value);
        end

        % Value changed function: FocusXSlider
        function FocusXSliderValueChanged(app, event)
            app.focus(1) = app.FocusXSlider.Value/1000;
            app.FocusXSliderLabel.Text = sprintf('Focus X: %0.2f (mm)',app.FocusXSlider.Value);
        end

        % Value changed function: FocusZSlider
        function FocusZSliderValueChanged(app, event)
            app.focus(3) = app.FocusZSlider.Value/1000;
            app.FocusZSliderLabel.Text = sprintf('Focus Z: %0.2f (mm)',app.FocusZSlider.Value);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FocusXSliderLabel = uilabel(app.UIFigure);
            app.FocusXSliderLabel.Text = 'Focus X';
            app.FocusXSlider = uislider(app.UIFigure);
            app.FocusXSlider.ValueChangedFcn = createCallbackFcn(app, @FocusXSliderValueChanged, true);
            app.FocusZSliderLabel = uilabel(app.UIFigure);
            app.FocusZSliderLabel.Text = 'Focus Z';
            app.FocusZSlider = uislider(app.UIFigure);
            app.FocusZSlider.ValueChangedFcn = createCallbackFcn(app, @FocusZSliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GeometricModel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end