classdef dataviz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        DisplayIntensityProfileCheckBox  matlab.ui.control.CheckBox
        XSliderLabel                matlab.ui.control.Label
        XSlider                     matlab.ui.control.Slider
        YSliderLabel                matlab.ui.control.Label
        YSlider                     matlab.ui.control.Slider
        ResettoFocusButton          matlab.ui.control.Button
        FlipIntensityProfileSwitch  matlab.ui.control.Switch
        UnitsDropDownLabel          matlab.ui.control.Label
        UnitsDropDown               matlab.ui.control.DropDown
        PlaneButtonGroup            matlab.ui.container.ButtonGroup
        xyButton                    matlab.ui.control.RadioButton
        xzButton                    matlab.ui.control.RadioButton
        yzButton                    matlab.ui.control.RadioButton
        FocusLabel                  matlab.ui.control.Label
        FXSliderLabel               matlab.ui.control.Label
        FXSlider                    matlab.ui.control.Slider
        FYSliderLabel               matlab.ui.control.Label
        FYSlider                    matlab.ui.control.Slider
        FZSliderLabel               matlab.ui.control.Label
        FZSlider                    matlab.ui.control.Slider
        WarningLabel                matlab.ui.control.Label
        FWHMLabel                   matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
        ExcitationButtonGroup       matlab.ui.container.ButtonGroup
        GaussianButton              matlab.ui.control.RadioButton
        SineWaveButton              matlab.ui.control.RadioButton
        FieldSummationButtonGroup   matlab.ui.container.ButtonGroup
        MaxButton                   matlab.ui.control.RadioButton
        MaxHilbertButton            matlab.ui.control.RadioButton
        SumHilbertButton            matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        data % sensor data
        kwave_struct_filename
        kwave_struct
        kgrid % kgrid defined for the simulation sensor data came from
        current_params % struct simulation parameters
        parameters
        x % x vector of sensor mask
        y % y vector of sensor mask
        z % z vector of sensor mask
        max_p
        min_p
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, kwave_struct_filename)
            app.kwave_struct = load(kwave_struct_filename);
            app.kgrid = app.kwave_struct.kgrid;
            app.kwave_struct = rmfield(app.kwave_struct,'kgrid');
            app.parameters = unique_vals_from_mat(app.kwave_struct);
          
            % Initialize with the first data structure
            fields= fieldnames(app.kwave_struct);
            app.current_params = fieldname_to_param(fields{1});
            FI = {'FX','FY','FZ'};
            
            for i = 1:length(FI)
                FI_slider = strcat(FI{i},'Slider');
                if length(app.parameters.(FI{i}))>1
                    app.(FI_slider).Limits = [app.parameters.(FI{i})(1),app.parameters.(FI{i})(end)];
                    app.(FI_slider).MajorTicks = app.parameters.(FI{i});     
                else
                    app.(FI_slider).Visible = 'off';
                end
                app.(FI_slider).Value = app.current_params.(FI{i});
            end
            app.DisplayIntensityProfileCheckBox.Value=true;
            app.WarningLabel.FontColor ='r';
            app.FWHMLabel.Text= '';
            % Configure Axes
            axes_size = 0.4;
            %app.UIAxes.Position = [0.5 0.55 axes_size axes_size];
            
            
            %app.UIAxes_2.Position = [0.5 0.1 axes_size axes_size];
            app.UIAxes.PlotBoxAspectRatio = [1,1,1];
            app.UIAxes_2.PlotBoxAspectRatio = [1,1,1];
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.XSliderLabel.Text = sprintf('X: %0.2f',app.XSlider.Value);
            app.YSliderLabel.Text = sprintf('Y: %0.2f',app.YSlider.Value);
      
            app.current_params.EX = app.parameters.EX{1};
            app.current_params.SUM = app.parameters.SUM{1};
            if strcmp(app.current_params.EX,'g')
                app.GaussianButton.Value = 1;
            else
                app.SineWaveButton.Value = 1;
                app.current_params.EX = 's';
            end
            
            switch app.current_params.SUM
                case 'ms'
                    app.MaxButton.Value = 1;
                case 'mh'
                    app.MaxHilbertButton.Value = 1;
                otherwise 'sh';
                    app.SumHilbertButton.Value = 1;
                    app.current_params.SUM = 'sh';
            end
            app = find_data(app);

            plot_kwave(app);
        end

        % Value changed function: DisplayIntensityProfileCheckBox
        function DisplayIntensityProfileCheckBoxValueChanged(app, event)
            plot_kwave(app);
        end

        % Value changed function: FlipIntensityProfileSwitch
        function FlipIntensityProfileSwitchValueChanged(app, event)
            plot_kwave(app);
        end

        % Button pushed function: ResettoFocusButton
        function ResettoFocusButtonPushed(app, event)
            switch app.current_params.Slice
                case 'xy'
                    app.XSlider.Value = app.current_params.FX;
                    app.YSlider.Value = app.current_params.FY;
                case 'xz'
                    app.XSlider.Value = app.current_params.FX;
                    app.YSlider.Value = app.current_params.FZ;
                case 'yz'
                    app.XSlider.Value = app.current_params.FY;
                    app.YSlider.Value = app.current_params.FZ;
            end
            app.XSliderLabel.Text = sprintf('X: %0.2f',app.XSlider.Value);
            app.YSliderLabel.Text = sprintf('Y: %0.2f',app.YSlider.Value);
            plot_kwave(app);
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            app.XSliderLabel.Text = sprintf('X: %0.2f',app.XSlider.Value);
            plot_kwave(app);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            app.YSliderLabel.Text = sprintf('Y: %0.2f',app.YSlider.Value);
            plot_kwave(app);
        end

        % Selection changed function: PlaneButtonGroup
        function PlaneButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlaneButtonGroup.SelectedObject;
            app.current_params.Slice = selectedButton.Text;
            plot_kwave(app);
        end

        % Value changed function: FXSlider
        function FXSliderValueChanged(app, event)
            [~,i] = min(abs(app.parameters.FX - app.FXSlider.Value));
            app.FXSlider.Value = app.parameters.FX(i);
            app.current_params.FX = app.FXSlider.Value;
            plot_kwave(app);
        end

        % Value changed function: FYSlider
        function FYSliderValueChanged(app, event)
            [~,i] = min(abs(app.parameters.FY - app.FYSlider.Value));
            app.FYSlider.Value = app.parameters.FY(i);
            app.current_params.FY = app.FYSlider.Value;
            plot_kwave(app);
        end

        % Value changed function: FZSlider
        function FZSliderValueChanged(app, event)
            [~,i] = min(abs(app.parameters.FZ - app.FZSlider.Value));
            app.FZSlider.Value = app.parameters.FZ(i);
            app.current_params.FZ = app.FZSlider.Value;
            plot_kwave(app);
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            plot_kwave(app);
        end

        % Selection changed function: ExcitationButtonGroup
        function ExcitationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExcitationButtonGroup.SelectedObject;
            disp(selectedButton.Text);
            switch selectedButton.Text
                case 'Guassian'
                    app.current_params.EX = 'g';
                case 'SineWave'
                    app.current_params.EX = 's';
            end
            
        end

        % Selection changed function: FieldSummationButtonGroup
        function FieldSummationButtonGroupSelectionChanged(app, event)
            selectedButton = app.FieldSummationButtonGroup.SelectedObject;
            disp(selectedButton.Text);
            switch selectedButton.Text
                case 'Max'
                    app.current_params.SUM = 'ms';
                case 'MaxHilbert'
                    app.current_params.SUM = 'mh';
                case 'SumHilbert'
                    app.current_params.SUM = 'sh';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'xaxis')
            ylabel(app.UIAxes, 'yaxis')
            app.DisplayIntensityProfileCheckBox = uicheckbox(app.UIFigure);
            app.DisplayIntensityProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayIntensityProfileCheckBoxValueChanged, true);
            app.DisplayIntensityProfileCheckBox.Text = 'Display Intensity Profile';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ResettoFocusButton = uibutton(app.UIFigure, 'push');
            app.ResettoFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoFocusButtonPushed, true);
            app.ResettoFocusButton.Text = 'Reset to Focus';
            app.FlipIntensityProfileSwitch = uiswitch(app.UIFigure, 'slider');
            app.FlipIntensityProfileSwitch.Items = {'', 'Flip Intensity Profile'};
            app.FlipIntensityProfileSwitch.ValueChangedFcn = createCallbackFcn(app, @FlipIntensityProfileSwitchValueChanged, true);
            app.FlipIntensityProfileSwitch.Value = '';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'dB', 'Pressure'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'dB';
            app.PlaneButtonGroup = uibuttongroup(app.UIFigure);
            app.PlaneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlaneButtonGroupSelectionChanged, true);
            app.PlaneButtonGroup.Title = 'Plane';
            app.xyButton = uiradiobutton(app.PlaneButtonGroup);
            app.xyButton.Text = 'xy';
            app.xyButton.Value = true;
            app.xzButton = uiradiobutton(app.PlaneButtonGroup);
            app.xzButton.Text = 'xz';
            app.yzButton = uiradiobutton(app.PlaneButtonGroup);
            app.yzButton.Text = 'yz';
            app.FocusLabel = uilabel(app.UIFigure);
            app.FocusLabel.Text = 'Focus';
            app.FXSliderLabel = uilabel(app.UIFigure);
            app.FXSliderLabel.Text = 'FX';
            app.FXSlider = uislider(app.UIFigure);
            app.FXSlider.ValueChangedFcn = createCallbackFcn(app, @FXSliderValueChanged, true);
            app.FYSliderLabel = uilabel(app.UIFigure);
            app.FYSliderLabel.Text = 'FY';
            app.FYSlider = uislider(app.UIFigure);
            app.FYSlider.ValueChangedFcn = createCallbackFcn(app, @FYSliderValueChanged, true);
            app.FZSliderLabel = uilabel(app.UIFigure);
            app.FZSliderLabel.Text = 'FZ';
            app.FZSlider = uislider(app.UIFigure);
            app.FZSlider.ValueChangedFcn = createCallbackFcn(app, @FZSliderValueChanged, true);
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Text = 'Warning';
            app.FWHMLabel = uilabel(app.UIFigure);
            app.FWHMLabel.Text = 'FWHM';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'xaxis')
            ylabel(app.UIAxes_2, 'yaxis')
            app.ExcitationButtonGroup = uibuttongroup(app.UIFigure);
            app.ExcitationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExcitationButtonGroupSelectionChanged, true);
            app.ExcitationButtonGroup.Title = 'Excitation';
            app.GaussianButton = uiradiobutton(app.ExcitationButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.GaussianButton.Value = true;
            app.SineWaveButton = uiradiobutton(app.ExcitationButtonGroup);
            app.SineWaveButton.Text = 'Sine Wave';
            app.FieldSummationButtonGroup = uibuttongroup(app.UIFigure);
            app.FieldSummationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FieldSummationButtonGroupSelectionChanged, true);
            app.FieldSummationButtonGroup.Title = 'Field Summation';
            app.MaxButton = uiradiobutton(app.FieldSummationButtonGroup);
            app.MaxButton.Text = 'Max';
            app.MaxButton.Value = true;
            app.MaxHilbertButton = uiradiobutton(app.FieldSummationButtonGroup);
            app.MaxHilbertButton.Text = 'Max Hilbert';
            app.SumHilbertButton = uiradiobutton(app.FieldSummationButtonGroup);
            app.SumHilbertButton.Text = 'Sum Hilbert';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataviz(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end