classdef timeseries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PlayButton       matlab.ui.control.Button
        TimeSlider       matlab.ui.control.Slider
        TimeSliderLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         image_matrix;% Description
         x
         y
         image
         im_min
         im_max
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image_matrix)
            app.image_matrix = image_matrix;
            app.im_max = max(app.image_matrix,[],'all');
            app.im_min = min(app.image_matrix,[],'all');
            app.x = size(image_matrix,2);
            app.y = size(image_matrix, 3);
            app.TimeSlider.Limits = [1, size(app.image_matrix,1)];
            app.TimeSlider.MajorTicks = (1:10:size(app.image_matrix));
            app.image = reshape(app.image_matrix(app.TimeSlider.Value,:,:),[app.x app.y]);
            imagesc(app.UIAxes, app.image);
            daspect([1 1 1])
            caxis(app.UIAxes, [app.im_min app.im_max]);
            colorbar(app.UIAxes);
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            app.TimeSlider.Value = round(app.TimeSlider.Value);
            app.image = reshape(app.image_matrix(app.TimeSlider.Value,:,:),[app.x app.y]);
            imagesc(app.UIAxes, app.image);
            daspect([1 1 1])
            caxis(app.UIAxes, [app.im_min app.im_max]);
            colorbar(app.UIAxes);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.TimeSlider.Value = round(changingValue);
            app.image = reshape(app.image_matrix(app.TimeSlider.Value,:,:),[app.x app.y]);
            
            imagesc(app.UIAxes, app.image); 
            daspect([1 1 1])
            caxis(app.UIAxes, [app.im_min app.im_max]);
            colorbar(app.UIAxes);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            for i = 1:app.TimeSlider.Limits(2)
                app.TimeSlider.Value = i;
                app.image = reshape(app.image_matrix(app.TimeSlider.Value,:,:),[app.x app.y]);
                
                imagesc(app.UIAxes, app.image); 
                daspect([1 1 1])
                pause(3/size(app.image, 1));
                
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timeseries(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end